classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        PlotsTab                        matlab.ui.container.TabGroup
        CustomerVoltagesTab             matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        error                           matlab.ui.control.UIAxes
        estCustVolt                     matlab.ui.control.UIAxes
        custVolt                        matlab.ui.control.UIAxes
        ActualStateTab                  matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        errorState                      matlab.ui.control.UIAxes
        estState                        matlab.ui.control.UIAxes
        actualState                     matlab.ui.control.UIAxes
        PredictorsTab                   matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        predictorError                  matlab.ui.control.UIAxes
        timers                          matlab.ui.control.UIAxes
        predVsOutput                    matlab.ui.control.UIAxes
        MultiObserverTab                matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        ObserverTable                   matlab.ui.control.Table
        observerUsage                   matlab.ui.control.UIAxes
        ObserverstatesTab               matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        secondaryObservers              matlab.ui.control.UIAxes
        primaryObservers                matlab.ui.control.UIAxes
        AttackTab                       matlab.ui.container.Tab
        BestObserver                    matlab.ui.control.EditField
        AttackedOutputs                 matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.TabGroup
        SystemTab                       matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ReferenceVoltageEditField       matlab.ui.control.EditField
        ReferenceVoltageEditFieldLabel  matlab.ui.control.Label
        InputfilenameEditField          matlab.ui.control.EditField
        InputfilenameEditFieldLabel     matlab.ui.control.Label
        AttacksignalEditField           matlab.ui.control.EditField
        AttacksignalEditFieldLabel      matlab.ui.control.Label
        SubstationvoltageEditFieldLabel  matlab.ui.control.Label
        NumberofCustomersSpinner        matlab.ui.control.Spinner
        NumberofCustomersSpinnerLabel   matlab.ui.control.Label
        NumberofAttacksSpinner          matlab.ui.control.Spinner
        NumberofAttacksSpinnerLabel     matlab.ui.control.Label
        OutputstobeattackedNoselectionchoosesrandomoutputsListBox  matlab.ui.control.ListBox
        OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel  matlab.ui.control.Label
        SubstationvoltageEditField      matlab.ui.control.EditField
        SystemParametersLabel           matlab.ui.control.Label
        ObserverTab                     matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        UseKobservergainCheckBox        matlab.ui.control.CheckBox
        MinimumnuSlider                 matlab.ui.control.Slider
        MinimumnuSliderLabel            matlab.ui.control.Label
        PredictorgainSlider             matlab.ui.control.Slider
        PredictorgainSliderLabel        matlab.ui.control.Label
        PredictorsCheckBox              matlab.ui.control.CheckBox
        AutomaticsecondaryobserversizingCheckBox  matlab.ui.control.CheckBox
        SecondaryobserveroutputsSpinner  matlab.ui.control.Spinner
        SecondaryobserveroutputsSpinnerLabel  matlab.ui.control.Label
        AutomaticprimaryobserversizingCheckBox  matlab.ui.control.CheckBox
        PrimaryobserveroutputsSpinner   matlab.ui.control.Spinner
        PrimaryobserveroutputsLabel     matlab.ui.control.Label
        Maximummu_dSlider               matlab.ui.control.Slider
        Maximummu_dSliderLabel          matlab.ui.control.Label
        Minimummu_aSlider               matlab.ui.control.Slider
        mu_aLabel                       matlab.ui.control.Label
        ObserverParametersLabel         matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ObserveroffsetEditField         matlab.ui.control.NumericEditField
        ObserveroffsetEditFieldLabel    matlab.ui.control.Label
        SolveObserversCheckBox          matlab.ui.control.CheckBox
        NoisesamplingfrequencyEditField  matlab.ui.control.NumericEditField
        NoisesamplingfrequencyLabel     matlab.ui.control.Label
        NoisevarianceSlider             matlab.ui.control.Slider
        NoisevarianceSliderLabel        matlab.ui.control.Label
        InitialConditionsEditField      matlab.ui.control.EditField
        InitialConditionsEditFieldLabel  matlab.ui.control.Label
        SimulationTimesSlider           matlab.ui.control.Slider
        SimulationTimesSliderLabel      matlab.ui.control.Label
        SimulationParametersLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            addpath("classes\")
            addpath("functions\")
            
            %% clear app contents
            clearvars PDMO inputs outputs noise attack lineEntries legendEntries;
            cla(app.error);
            cla(app.custVolt);
            cla(app.estCustVolt);
            cla(app.estState);
            cla(app.actualState);
            cla(app.errorState);
            cla(app.observerUsage);
            cla(app.predVsOutput);
            delete(findall(app.predVsOutput, 'Type', 'line'))
            cla(app.timers);
            cla(app.predictorError);
            cla(app.primaryObservers);
            cla(app.secondaryObservers)
            
            %% extract inputs
            inputs.inputFileName   = app.InputfilenameEditField.Value;
            inputs.numCustomers    = app.NumberofCustomersSpinner.Value;
            inputs.attack.num     = app.NumberofAttacksSpinner.Value;
            inputs.attack.attackedOutputs = app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Value;
            inputs.attack.func      = str2func(['@(t) 0*t +' app.AttacksignalEditField.Value]);
            inputs.sys.v0           = str2func(['@(t) 0*t +',app.SubstationvoltageEditField.Value]);
            inputs.sys.vref         = str2func(['@(t) 0*t +',app.ReferenceVoltageEditField.Value]);
            
            if isempty(inputs.attack.attackedOutputs)
                inputs.attack.attackedOutputs = "";
            end
            inputs.attack.attackedOutputs = str2num(inputs.attack.attackedOutputs); %#ok<ST2NM>
            if app.AutomaticprimaryobserversizingCheckBox.Value == 0
                inputs.obsv.primObsvOutputs = app.PrimaryobserveroutputsSpinner.Value;
            else
                inputs.obsv.primObsvOutputs = 0;
            end
            if app.AutomaticsecondaryobserversizingCheckBox.Value == 0
                inputs.obsv.secObsvOutputs = app.SecondaryobserveroutputsSpinner.Value;
            else
                inputs.obsv.secObsvOutputs = 0;
            end
            inputs.obsv.size = [inputs.obsv.primObsvOutputs inputs.obsv.secObsvOutputs];
            inputs.tspan           = [0 app.SimulationTimesSlider.Value];
            inputs.x0              = app.InitialConditionsEditField.Value;
            if isempty(inputs.x0)
                inputs.x0 = -0.5 + rand(numCustomers,1);
            else
                inputs.x0 = str2double(inputs.x0);
            end
            if size(inputs.x0,2) == 1
                inputs.x0 = repmat(inputs.x0,1,inputs.numCustomers);
            end
            assert(size(inputs.x0,2) == inputs.numCustomers,...
                "The amount of initial conditions (%d) does not equal the number of customers (%d)",...
                size(inputs.x0,2),inputs.numCustomers);
            inputs.observerOffset = app.ObserveroffsetEditField.Value;

            inputs.LMIconsts.mua = app.Minimummu_aSlider.Value;
            inputs.LMIconsts.mud = app.Maximummu_dSlider.Value;
            inputs.LMIconsts.nu  = app.MinimumnuSlider.Value;

            inputs.noiseVar = app.NoisevarianceSlider.Value;
            inputs.sampleFreq = app.NoisesamplingfrequencyEditField.Value;
            inputs.predictors.enabled = app.PredictorsCheckBox.Value;
            inputs.predictors.gain = app.PredictorgainSlider.Value;

            inputs.useKgain = app.UseKobservergainCheckBox.Value;
            
            %% create multi-observer
            noise  = Noise(inputs);
            attack = Attack(inputs);
            PDMO   = PowerDistMO(inputs,attack,noise);

            outputs = PDMO.solve('tspan',inputs.tspan,'x0sys',inputs.x0,'observerOffset',inputs.observerOffset);

            %% create plots
            % plot customer voltages
            grayColor = [.7 .7 .7];
            if ~isempty(outputs.v)
                plot(app.custVolt,outputs.t,[PDMO.vref(outputs.t); PDMO.v0(outputs.t)],"LineStyle",'-','LineWidth',2); 
                hold(app.custVolt,'on');
                plot(app.custVolt,outputs.t,outputs.v,'LineStyle','--');
                yline(app.custVolt,230,'--','Color',grayColor)
                yline(app.custVolt,(230*1.1),'--','Color',grayColor)
                yline(app.custVolt,(230*0.9),'--','Color',grayColor)
                yticks(app.custVolt,[(0.9*230); 230; (1.1*230)])
                yticklabels(app.custVolt,{'(0.9*230)', '230', '(1.1*230)'})
                legendEntries = arrayfun(@(x) sprintf('v_%d', x), 0:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.custVolt,['v_{ref}' legendEntries])
            end
            xlim(app.custVolt,inputs.tspan)
            ylim(app.custVolt,[(0.85*230) (1.15*230)])

            % plot estimated customer voltages
            if ~isempty(outputs.vest)
                plot(app.estCustVolt,outputs.t,[PDMO.vref(outputs.t); PDMO.v0(outputs.t)],"LineStyle",'-','LineWidth',2);
                hold(app.estCustVolt,'on');
                plot(app.estCustVolt,outputs.t,outputs.vest,'LineStyle','--');
                % add lines on upper and lower limits
                yline(app.estCustVolt,230,'--','Color',grayColor)
                yline(app.estCustVolt,(230*1.1),'--','Color',grayColor)
                yline(app.estCustVolt,(230*0.9),'--','Color',grayColor)
                % add vertical ticks on the limit lines
                yticks(app.estCustVolt,[(0.9*230), 230, (1.1*230)])
                yticklabels(app.estCustVolt,{'(0.9*230)', '230', '(1.1*230)'})
                legendEntries = arrayfun(@(x) sprintf('v_%d', x), 0:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.estCustVolt,['v_{ref}' legendEntries])
            end
            xlim(app.estCustVolt,inputs.tspan)
            ylim(app.estCustVolt,[(0.85*230) (1.15*230)])

            % plot multi observer voltage error
            if ~isempty(outputs.verr)
                colorOrder = get(app.custVolt, 'ColorOrder'); % Get default color order
                set(app.error,'ColorOrder',colorOrder(3:end,:));
                plot(app.error,outputs.t,outputs.verr)
                legendEntries = arrayfun(@(x) sprintf('e_%d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.error,legendEntries);
            end
            xlim(app.error,inputs.tspan);
            grid(app.error,"on");

            % plot actual state
            if ~isempty(outputs.state)
                plot(app.actualState,outputs.t,outputs.state);
                legendEntries = arrayfun(@(x) sprintf('x_%d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.actualState,legendEntries)
            end
            xlim(app.actualState,inputs.tspan)

            % plot estimated state
            if ~isempty(outputs.xBestEst)
                plot(app.estState,outputs.t,outputs.xBestEst);
                legendEntries = arrayfun(@(x) sprintf('x_%d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.estState,legendEntries)
            end
            xlim(app.estState,inputs.tspan)

            % plot multi observer state error
            errnorm = zeros(1,size(outputs.xerr,2));
            for i = 1:size(outputs.xerr,2)
                errnorm(:,i) = norm(outputs.xerr(:,i));
            end
            if ~isempty(outputs.xerr)
                plot(app.errorState,outputs.t,errnorm)
                legendEntries = arrayfun(@(x) sprintf('e_%d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.errorState,legendEntries);
            end
            xlim(app.errorState,inputs.tspan);
            grid(app.errorState,"on");

            % plot observer usage
            if ~isempty(outputs.bestObsv)
                for i = 1:PDMO.numObservers
                    if  isempty(intersect(PDMO.attack.attackedOutputs, PDMO.primaryMO.CIndices(i,:)))
                        break
                    end
                end
                highlightIndices = find(outputs.bestObsv == i); % Find indices where y equals 5
                stem(app.observerUsage,outputs.t,outputs.bestObsv); 
                hold(app.observerUsage,'on');
                stem(app.observerUsage,outputs.t(highlightIndices), outputs.bestObsv(highlightIndices), 'r', 'LineWidth', 1); % Plot the 5th stem in red
                hold(app.observerUsage,'off');
            end

            % plot predictors and outputs
            if ~isempty(outputs.y)
                lineHandles = plot(app.predVsOutput,outputs.t,outputs.y,LineStyle='-');
                legendEntries = arrayfun(@(x) sprintf('y_%d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.predVsOutput,legendEntries)
            end
            if ~isempty(outputs.yhat)
                hold(app.predVsOutput,"on")
                for i = 1:inputs.numCustomers
                    plot(app.predVsOutput,outputs.t,outputs.yhat(i,:),'LineStyle','--', 'HandleVisibility', 'off','Color',lineHandles(i).Color)
                end
                hold(app.predVsOutput, "off");
                subtitle(app.predVsOutput,"Solid line: actual output, dashed line: predictor output")
            end

            % plot timers
            if ~isempty(outputs.timers)
                plot(app.timers,outputs.t,outputs.timers)
                legendEntries = arrayfun(@(x) sprintf('Timer %d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.timers,legendEntries);
                grid(app.timers,"on")
            end

            % plot predictor error
            if ~isempty(outputs.predictorError)
                plot(app.predictorError,outputs.t,outputs.predictorError)
                legendEntries = arrayfun(@(x) sprintf('Timer %d', x), 1:PDMO.numCustomers, 'UniformOutput', false);
                legend(app.timers,legendEntries);
                grid(app.timers,"on")
            end

            % plot primary and secondary observers
            if ~isempty(outputs.xPrim)
                colorOrder = get(app.primaryObservers, 'ColorOrder'); % Get default color order
                for o = 1:PDMO.primaryMO.numObservers
                    plot(app.primaryObservers,outputs.t,outputs.xPrim(:,:,o),"Color",colorOrder(o,:));
                    hold(app.primaryObservers,"on")
                end
                hold(app.primaryObservers,"off")
            end

            if ~isempty(outputs.xSec)
                colorOrder = get(app.secondaryObservers, 'ColorOrder'); % Get default color order
                for o = 1:PDMO.secondaryMO.numObservers
                    plot(app.secondaryObservers,outputs.t,outputs.xSec(:,:,o),"Color",colorOrder(mod(o,6),:));
                    hold(app.secondaryObservers,"on")
                end
                hold(app.secondaryObservers,"off")
            end


            % edit text values below plots
            app.AttackedOutputs.Value = strcat("Attacked Outputs: ", num2str(PDMO.attack.attackedOutputs));
            app.BestObserver.Value    = strcat("Best Observer: ", num2str(outputs.bestObsv(end)), ", Outputs: ", num2str(PDMO.primaryMO.CIndices(outputs.bestObsv(end),:)));
            columns = arrayfun(@(x) sprintf('Output %d', x), 1:PDMO.primaryMO.numOutputsObserver, 'UniformOutput', false);
            app.ObserverTable.ColumnName = ["Observer",columns];
            observerCol = 1:PDMO.primaryMO.numObservers;
            app.ObserverTable.Data =  [observerCol' PDMO.primaryMO.CIndices];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.SettingsTab = uitabgroup(app.GridLayout2);
            app.SystemTab = uitab(app.SettingsTab);
            app.SystemTab.Title = 'System';
            app.GridLayout = uigridlayout(app.SystemTab);
            app.SystemParametersLabel = uilabel(app.GridLayout);
            app.SystemParametersLabel.Text = 'System Parameters';
            app.SubstationvoltageEditField = uieditfield(app.GridLayout, 'text');
            app.SubstationvoltageEditField.Tooltip = {'The substation voltage signal. Input is parsed by str2func, the anonymous function handle @(t) is added in the backend.'};
            app.SubstationvoltageEditField.Value = '230 + 5*sin(t)';
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel = uilabel(app.GridLayout);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBoxLabel.Text = {'Outputs to be attacked'; '(No selection  chooses'; 'random outputs, hold control to select multiple)'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox = uilistbox(app.GridLayout);
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50'};
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Multiselect = 'on';
            app.OutputstobeattackedNoselectionchoosesrandomoutputsListBox.Value = {};
            app.NumberofAttacksSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofAttacksSpinnerLabel.Text = 'Number of Attacks';
            app.NumberofAttacksSpinner = uispinner(app.GridLayout);
            app.NumberofAttacksSpinner.Limits = [0 50];
            app.NumberofCustomersSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofCustomersSpinnerLabel.Text = 'Number of Customers';
            app.NumberofCustomersSpinner = uispinner(app.GridLayout);
            app.NumberofCustomersSpinner.Limits = [0 50];
            app.NumberofCustomersSpinner.Value = 5;
            app.SubstationvoltageEditFieldLabel = uilabel(app.GridLayout);
            app.SubstationvoltageEditFieldLabel.Text = 'Substation voltage';
            app.AttacksignalEditFieldLabel = uilabel(app.GridLayout);
            app.AttacksignalEditFieldLabel.Text = 'Attack signal';
            app.AttacksignalEditField = uieditfield(app.GridLayout, 'text');
            app.AttacksignalEditField.Tooltip = {'The attack signal used on all attacked outputs. Input is parsed by str2func, the anonymous function handle @(t) is added in the backend.'};
            app.AttacksignalEditField.Value = '1000*t';
            app.InputfilenameEditFieldLabel = uilabel(app.GridLayout);
            app.InputfilenameEditFieldLabel.Text = 'Input file name';
            app.InputfilenameEditField = uieditfield(app.GridLayout, 'text');
            app.InputfilenameEditField.Tooltip = {'If this field is left empty a standard system is generated using the settings below.'};
            app.ReferenceVoltageEditFieldLabel = uilabel(app.GridLayout);
            app.ReferenceVoltageEditFieldLabel.Text = 'Reference Voltage';
            app.ReferenceVoltageEditField = uieditfield(app.GridLayout, 'text');
            app.ReferenceVoltageEditField.Value = '230';
            app.ObserverTab = uitab(app.SettingsTab);
            app.ObserverTab.Title = 'Observer';
            app.GridLayout7 = uigridlayout(app.ObserverTab);
            app.ObserverParametersLabel = uilabel(app.GridLayout7);
            app.ObserverParametersLabel.Text = 'Observer Parameters';
            app.mu_aLabel = uilabel(app.GridLayout7);
            app.mu_aLabel.Interpreter = 'tex';
            app.mu_aLabel.Text = 'Minimum \mu_a';
            app.Minimummu_aSlider = uislider(app.GridLayout7);
            app.Minimummu_aSlider.Limits = [0 1000];
            app.Maximummu_dSliderLabel = uilabel(app.GridLayout7);
            app.Maximummu_dSliderLabel.Interpreter = 'tex';
            app.Maximummu_dSliderLabel.Text = 'Maximum \mu_d';
            app.Maximummu_dSlider = uislider(app.GridLayout7);
            app.Maximummu_dSlider.Limits = [0 1];
            app.Maximummu_dSlider.Value = 1;
            app.PrimaryobserveroutputsLabel = uilabel(app.GridLayout7);
            app.PrimaryobserveroutputsLabel.Text = {'Primary observer'; 'outputs'};
            app.PrimaryobserveroutputsSpinner = uispinner(app.GridLayout7);
            app.PrimaryobserveroutputsSpinner.Limits = [1 Inf];
            app.PrimaryobserveroutputsSpinner.Value = 2;
            app.AutomaticprimaryobserversizingCheckBox = uicheckbox(app.GridLayout7);
            app.AutomaticprimaryobserversizingCheckBox.Text = 'Automatic primary observer sizing';
            app.AutomaticprimaryobserversizingCheckBox.Value = true;
            app.SecondaryobserveroutputsSpinnerLabel = uilabel(app.GridLayout7);
            app.SecondaryobserveroutputsSpinnerLabel.Text = {'Secondary observer'; 'outputs'};
            app.SecondaryobserveroutputsSpinner = uispinner(app.GridLayout7);
            app.SecondaryobserveroutputsSpinner.Limits = [1 Inf];
            app.SecondaryobserveroutputsSpinner.Value = 1;
            app.AutomaticsecondaryobserversizingCheckBox = uicheckbox(app.GridLayout7);
            app.AutomaticsecondaryobserversizingCheckBox.Text = 'Automatic secondary observer sizing';
            app.AutomaticsecondaryobserversizingCheckBox.Value = true;
            app.PredictorsCheckBox = uicheckbox(app.GridLayout7);
            app.PredictorsCheckBox.Text = 'Predictors';
            app.PredictorsCheckBox.Value = true;
            app.PredictorgainSliderLabel = uilabel(app.GridLayout7);
            app.PredictorgainSliderLabel.Interpreter = 'latex';
            app.PredictorgainSliderLabel.Text = 'Predictor gain';
            app.PredictorgainSlider = uislider(app.GridLayout7);
            app.PredictorgainSlider.Limits = [0 10];
            app.MinimumnuSliderLabel = uilabel(app.GridLayout7);
            app.MinimumnuSliderLabel.Interpreter = 'latex';
            app.MinimumnuSliderLabel.Text = 'Minimum \nu';
            app.MinimumnuSlider = uislider(app.GridLayout7);
            app.MinimumnuSlider.Limits = [0 1000];
            app.UseKobservergainCheckBox = uicheckbox(app.GridLayout7);
            app.UseKobservergainCheckBox.Text = 'Use K observer gain';
            app.UseKobservergainCheckBox.Value = true;
            app.SimulationTab = uitab(app.SettingsTab);
            app.SimulationTab.Title = 'Simulation';
            app.GridLayout3 = uigridlayout(app.SimulationTab);
            app.SimulationParametersLabel = uilabel(app.GridLayout3);
            app.SimulationParametersLabel.Text = 'Simulation Parameters';
            app.SimulationTimesSliderLabel = uilabel(app.GridLayout3);
            app.SimulationTimesSliderLabel.Text = {'Simulation '; 'Time [s]'};
            app.SimulationTimesSlider = uislider(app.GridLayout3);
            app.SimulationTimesSlider.Limits = [0 50];
            app.SimulationTimesSlider.Value = 10;
            app.InitialConditionsEditFieldLabel = uilabel(app.GridLayout3);
            app.InitialConditionsEditFieldLabel.Text = 'Initial Conditions';
            app.InitialConditionsEditField = uieditfield(app.GridLayout3, 'text');
            app.InitialConditionsEditField.Value = '0';
            app.NoisevarianceSliderLabel = uilabel(app.GridLayout3);
            app.NoisevarianceSliderLabel.Text = 'Noise variance';
            app.NoisevarianceSlider = uislider(app.GridLayout3);
            app.NoisevarianceSlider.Limits = [0 1000];
            app.NoisesamplingfrequencyLabel = uilabel(app.GridLayout3);
            app.NoisesamplingfrequencyLabel.Text = {'Noise sampling '; 'frequency'};
            app.NoisesamplingfrequencyEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NoisesamplingfrequencyEditField.Limits = [0 Inf];
            app.NoisesamplingfrequencyEditField.Tooltip = {'The amound of generated random numbers per second in the noise signal.'};
            app.NoisesamplingfrequencyEditField.Value = 20;
            app.SolveObserversCheckBox = uicheckbox(app.GridLayout3);
            app.SolveObserversCheckBox.Text = 'Solve Observers';
            app.SolveObserversCheckBox.Value = true;
            app.ObserveroffsetEditFieldLabel = uilabel(app.GridLayout3);
            app.ObserveroffsetEditFieldLabel.Tooltip = {'The maximum offset to all observers in the multi-observer. Offset is random within the half input.'};
            app.ObserveroffsetEditFieldLabel.Text = 'Observer offset';
            app.ObserveroffsetEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RunButton = uibutton(app.GridLayout2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Simulate the model with the entered parameters'};
            app.RunButton.Text = 'Run';
            app.AttackedOutputs = uieditfield(app.GridLayout2, 'text');
            app.AttackedOutputs.Editable = 'off';
            app.AttackedOutputs.Value = 'Attacked Outputs:';
            app.BestObserver = uieditfield(app.GridLayout2, 'text');
            app.BestObserver.Editable = 'off';
            app.BestObserver.Value = 'Best Observer:  Outputs:';
            app.PlotsTab = uitabgroup(app.GridLayout2);
            app.CustomerVoltagesTab = uitab(app.PlotsTab);
            app.CustomerVoltagesTab.Title = 'Customer Voltages';
            app.GridLayout4 = uigridlayout(app.CustomerVoltagesTab);
            app.custVolt = uiaxes(app.GridLayout4);
            title(app.custVolt, 'Customer Voltages')
            xlabel(app.custVolt, 'Time [s]')
            ylabel(app.custVolt, 'Voltage Squared [V^2]')
            zlabel(app.custVolt, 'Z')
            app.estCustVolt = uiaxes(app.GridLayout4);
            title(app.estCustVolt, 'Estimated Customer Voltages')
            xlabel(app.estCustVolt, 'Time [s]')
            ylabel(app.estCustVolt, 'Voltage Squared [V^2]')
            zlabel(app.estCustVolt, 'Z')
            app.error = uiaxes(app.GridLayout4);
            title(app.error, 'Observer error')
            xlabel(app.error, 'Time [s]')
            ylabel(app.error, 'Error [V^2]')
            zlabel(app.error, 'Z')
            app.ActualStateTab = uitab(app.PlotsTab);
            app.ActualStateTab.Title = 'Actual State';
            app.GridLayout6 = uigridlayout(app.ActualStateTab);
            app.actualState = uiaxes(app.GridLayout6);
            title(app.actualState, 'Actual State')
            xlabel(app.actualState, 'Time [s]')
            ylabel(app.actualState, {'Generated '; 'Active Power [W]'})
            zlabel(app.actualState, 'Z')
            app.estState = uiaxes(app.GridLayout6);
            title(app.estState, 'State Estimate')
            xlabel(app.estState, 'Time [s]')
            ylabel(app.estState, {'Generated'; 'Active Power [W]'})
            zlabel(app.estState, 'Z')
            app.errorState = uiaxes(app.GridLayout6);
            title(app.errorState, 'Estimation Error')
            xlabel(app.errorState, 'Time [s]')
            ylabel(app.errorState, 'Error [W]')
            zlabel(app.errorState, 'Z')
            app.PredictorsTab = uitab(app.PlotsTab);
            app.PredictorsTab.Title = 'Predictors';
            app.GridLayout8 = uigridlayout(app.PredictorsTab);
            app.predVsOutput = uiaxes(app.GridLayout8);
            title(app.predVsOutput, 'Measured and Predicted output')
            xlabel(app.predVsOutput, 'Time [s]')
            ylabel(app.predVsOutput, 'W')
            zlabel(app.predVsOutput, 'Z')
            app.timers = uiaxes(app.GridLayout8);
            title(app.timers, 'Timers')
            xlabel(app.timers, 'Time [s]')
            ylabel(app.timers, 'Time remaining [s]')
            zlabel(app.timers, 'Z')
            app.predictorError = uiaxes(app.GridLayout8);
            title(app.predictorError, 'Predictor error')
            xlabel(app.predictorError, 'Time [s]')
            ylabel(app.predictorError, 'Error [W]')
            zlabel(app.predictorError, 'Z')
            app.MultiObserverTab = uitab(app.PlotsTab);
            app.MultiObserverTab.Title = 'Multi Observer';
            app.GridLayout5 = uigridlayout(app.MultiObserverTab);
            app.observerUsage = uiaxes(app.GridLayout5);
            title(app.observerUsage, 'Best Observer')
            xlabel(app.observerUsage, 'Time [s]')
            ylabel(app.observerUsage, 'Observer ID')
            zlabel(app.observerUsage, 'Z')
            app.ObserverTable = uitable(app.GridLayout5);
            app.ObserverTable.ColumnName = {'Observer'; 'Output 1'; 'Output 2'; 'Output 4'};
            app.ObserverTable.RowName = {};
            app.ObserverstatesTab = uitab(app.PlotsTab);
            app.ObserverstatesTab.Title = 'Observer states';
            app.GridLayout9 = uigridlayout(app.ObserverstatesTab);
            app.primaryObservers = uiaxes(app.GridLayout9);
            title(app.primaryObservers, 'Primary observers')
            xlabel(app.primaryObservers, 'X')
            ylabel(app.primaryObservers, 'Y')
            zlabel(app.primaryObservers, 'Z')
            app.secondaryObservers = uiaxes(app.GridLayout9);
            title(app.secondaryObservers, 'Secondary observers')
            xlabel(app.secondaryObservers, 'X')
            ylabel(app.secondaryObservers, 'Y')
            zlabel(app.secondaryObservers, 'Z')
            app.AttackTab = uitab(app.PlotsTab);
            app.AttackTab.Title = 'Attack';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end